classdef PJR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NghimTab                       matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        KetQuaNghiem                   matlab.ui.control.EditField
        KtqunghimEditFieldLabel_2      matlab.ui.control.Label
        NhpkhongphnlynghimLabel_3      matlab.ui.control.Label
        TimNghiem                      matlab.ui.control.Button
        SaiSo_1                        matlab.ui.control.NumericEditField
        PhuongTrinh_2                  matlab.ui.control.EditField
        Nhpphngtrnh2Label              matlab.ui.control.Label
        PhanLyA                        matlab.ui.control.NumericEditField
        PhanLyB                        matlab.ui.control.NumericEditField
        PhuongTrinh_1                  matlab.ui.control.EditField
        ChonPPNghiem                   matlab.ui.control.DropDown
        ChnphngphptmnghimLabel         matlab.ui.control.Label
        SoLanLap                       matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel            matlab.ui.control.Label
        NhpsaischophpEditFieldLabel    matlab.ui.control.Label
        NhpphngtrnhEditFieldLabel      matlab.ui.control.Label
        DoThiNghiem                    matlab.ui.control.UIAxes
        NisuyTab                       matlab.ui.container.Tab
        GridLayout_2                   matlab.ui.container.GridLayout
        KetQuaDaThuc                   matlab.ui.control.EditField
        KtqudonLabel_3                 matlab.ui.control.Label
        KetQuaNoiSuy                   matlab.ui.control.EditField
        KtqudonLabel_2                 matlab.ui.control.Label
        Noisuy                         matlab.ui.control.Button
        DuLieuX_1                      matlab.ui.control.EditField
        DuLieuY_1                      matlab.ui.control.EditField
        NhpdliuyEditFieldLabel         matlab.ui.control.Label
        ChonPPNoiSuy                   matlab.ui.control.DropDown
        ChnphngphpnisuyDropDownLabel   matlab.ui.control.Label
        CanNoiSuy                      matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel   matlab.ui.control.Label
        NhpdliuxEditFieldLabel         matlab.ui.control.Label
        DoThiNoiSuy                    matlab.ui.control.UIAxes
        HiquyTab                       matlab.ui.container.Tab
        GridLayout_3                   matlab.ui.container.GridLayout
        KetQuaDuDoan                   matlab.ui.control.EditField
        KtqudonLabel                   matlab.ui.control.Label
        KtquphngtrnhhiquyEditFieldLabel_2  matlab.ui.control.Label
        KetQuaHoiQuy                   matlab.ui.control.EditField
        HoiQuy                         matlab.ui.control.Button
        DuLieuX_2                      matlab.ui.control.EditField
        DuLieuY_2                      matlab.ui.control.EditField
        NhpdliuyEditField_2Label       matlab.ui.control.Label
        ChonPPHoiQuy                   matlab.ui.control.DropDown
        ChnphngphphiquyDropDownLabel   matlab.ui.control.Label
        NhpgitrcndonEditFieldLabel     matlab.ui.control.Label
        NhpdliuxEditField_2Label       matlab.ui.control.Label
        DuDoanHoiQuy                   matlab.ui.control.NumericEditField
        DoThiHoiQuy                    matlab.ui.control.UIAxes
        ohmTab                         matlab.ui.container.Tab
        GridLayout_4                   matlab.ui.container.GridLayout
        BuocH                          matlab.ui.control.NumericEditField
        NhpbchLabel                    matlab.ui.control.Label
        Hoac_1                         matlab.ui.control.ToggleSwitch
        KetQuaDaoHam                   matlab.ui.control.EditField
        KtquEditFieldLabel_2           matlab.ui.control.Label
        DaoHam                         matlab.ui.control.Button
        ChonPPDaoHam                   matlab.ui.control.DropDown
        HamSo_1                        matlab.ui.control.EditField
        DuLieuY_3                      matlab.ui.control.EditField
        DuLieuX_3                      matlab.ui.control.EditField
        SaiSo_2                        matlab.ui.control.DropDown
        ChngitrsaisDropDownLabel       matlab.ui.control.Label
        ChnphngphpohmDropDownLabel     matlab.ui.control.Label
        NhphmsEditFieldLabel           matlab.ui.control.Label
        NhpdliuyEditField_3Label       matlab.ui.control.Label
        CanDaoHam                      matlab.ui.control.NumericEditField
        NhpgitrcntnhohmEditFieldLabel  matlab.ui.control.Label
        NhpdliuxEditField_3Label       matlab.ui.control.Label
        TchphnTab                      matlab.ui.container.Tab
        GridLayout_5                   matlab.ui.container.GridLayout
        KetQuaTichPhan                 matlab.ui.control.EditField
        KtquEditFieldLabel_3           matlab.ui.control.Label
        NhpNEditFieldLabel_2           matlab.ui.control.Label
        NhpkhongphnlynghimLabel_2      matlab.ui.control.Label
        TichPhan                       matlab.ui.control.Button
        DuLieuX_4                      matlab.ui.control.EditField
        DuLieuY_4                      matlab.ui.control.EditField
        ChonPPTichPhan                 matlab.ui.control.DropDown
        HocLabel_2                     matlab.ui.control.Label
        HamSo_2                        matlab.ui.control.EditField
        ChnphngphptchphnDropDownLabel  matlab.ui.control.Label
        NhphmsEditField_2Label         matlab.ui.control.Label
        NhpdliuyEditField_4Label       matlab.ui.control.Label
        NDoan                          matlab.ui.control.NumericEditField
        CanA                           matlab.ui.control.NumericEditField
        CanB                           matlab.ui.control.NumericEditField
        NhpdliuxEditField_4Label       matlab.ui.control.Label
        GiithiuNhmTab                  matlab.ui.container.Tab
        Image_7                        matlab.ui.control.Image
        Image_6                        matlab.ui.control.Image
        hoac_6                         matlab.ui.control.Label
        Image_5                        matlab.ui.control.Image
        Image_4                        matlab.ui.control.Image
        Image_3                        matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        hoac_5                         matlab.ui.control.Label
        hoac_4                         matlab.ui.control.Label
        hoac_3                         matlab.ui.control.Label
        hoac_2                         matlab.ui.control.Label
        hoac                           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TimNghiem
        function TimNghiemPushed(app, event)
            fx = matlabFunction(str2sym(app.PhuongTrinh_1.Value));
            fp = matlabFunction(str2sym(app.PhuongTrinh_2.Value));

            a = app.PhanLyA.Value;
            b = app.PhanLyB.Value;
            es = app.SaiSo_1.Value;
            pp = app.ChonPPNghiem.Value;

            [KQ, n, x] = FuncTimNghiem(fx, fp, a, b, es, pp);

            cla(app.DoThiNghiem);
            grid(app.DoThiNghiem, 'on');
            hold(app.DoThiNghiem, 'on');
            plot(app.DoThiNghiem, x, zeros(1, 100), 'k');
            if(pp ~= "Chia đôi")
                plot(app.DoThiNghiem, zeros(1, 100), x, 'k');
            end
            if(pp == "Lặp")
                plot(app.DoThiNghiem, x, fp(x),'b-', x, x,'g-', KQ, fp(KQ), 'r*');
            else
                plot(app.DoThiNghiem, x, fx(x),'b-', KQ, fx(KQ), 'r*');
            end

            app.KetQuaNghiem.Value = num2str(KQ);
            app.SoLanLap.Value = n;

        end

        % Button pushed function: Noisuy
        function NoisuyPushed(app, event)
            x = [str2num(app.DuLieuX_1.Value)];
            y = [str2num(app.DuLieuY_1.Value)];
            
            ns = app.CanNoiSuy.Value;
            pp = app.ChonPPNoiSuy.Value;

            n = length(x);
            x1 = linspace(x(1), x(n), 100);

            [KQ, PT, d] = FuncNoiSuy(x, y, n, x1, ns, pp);

            cla(app.DoThiNoiSuy);
            grid(app.DoThiNoiSuy, 'on');
            plot(app.DoThiNoiSuy, x, y, 'bo', x1, PT, 'g-', ns, KQ, 'r*');

            PT=[];
            for i=1:n
                if(d(i)~=0)
                    if(i==n)
                        D = num2str(d(i));
                    elseif(i==n-1)
                        D = strcat(num2str(d(i)), 'x');
                    else
                        D = strcat(num2str(d(i)), 'x^', num2str(n-i));
                    end
                    if(i>1 && d(i)>0)
                        PT = strcat(PT,'+',D);
                    else
                        PT = strcat(PT,D);
                    end
                end
            end

            app.KetQuaDaThuc.Value = num2str(PT);
            app.KetQuaNoiSuy.Value = num2str(KQ);

        end

        % Button pushed function: HoiQuy
        function HoiQuyPushed(app, event)
            x = [str2num(app.DuLieuX_2.Value)];
            y = [str2num(app.DuLieuY_2.Value)];
                       
            hq = app.DuDoanHoiQuy.Value;
            pp = app.ChonPPHoiQuy.Value;

            n = length(x);
            x1 = linspace(x(1), x(n), 100);

            [KQ, PT, A, B] = FuncHoiQuy(x, y, n, x1, hq, pp);

            cla(app.DoThiHoiQuy);
            grid(app.DoThiHoiQuy, 'on');
            plot(app.DoThiHoiQuy, x, y, 'bo', x1, PT, 'g-', hq, KQ, 'r*');         
            
            app.KetQuaHoiQuy.Value = ['a = ' num2str(A) '; b = ' num2str(B)];
            app.KetQuaDuDoan.Value = num2str(KQ);

        end

        % Button pushed function: DaoHam
        function DaoHamPushed(app, event)
            x = [str2num(app.DuLieuX_3.Value)];
            y = [str2num(app.DuLieuY_3.Value)];            
            
            fx = app.HamSo_1.Value;
            dh = app.CanDaoHam.Value;
            h = app.BuocH.Value;
            pp = app.ChonPPDaoHam.Value;
            ss = app.SaiSo_2.Value;
            Hoac = app.Hoac_1.Value;

            KQ = FuncDaoHam(x, y, fx, dh, h, pp, ss, Hoac);

            app.KetQuaDaoHam.Value = num2str(KQ);

        end

        % Button pushed function: TichPhan
        function TichPhanButtonPushed(app, event)
            x = [str2num(app.DuLieuX_4.Value)];
            y = [str2num(app.DuLieuY_4.Value)];
            
            fx = app.HamSo_2.Value;
            N = app.NDoan.Value;
            a = app.CanA.Value;
            b = app.CanB.Value;
            pp = app.ChonPPTichPhan.Value;

            KQ = FuncTichPhan(x, y, fx, N, a, b, pp);

            app.KetQuaTichPhan.Value = num2str(KQ);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.GridLayout = uigridlayout(app.NghimTab);
            app.DoThiNghiem = uiaxes(app.GridLayout);
            title(app.DoThiNghiem, 'Vẽ đồ thị hàm số')
            xlabel(app.DoThiNghiem, 'X')
            ylabel(app.DoThiNghiem, 'Y')
            zlabel(app.DoThiNghiem, 'Z')
            app.NhpphngtrnhEditFieldLabel = uilabel(app.GridLayout);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhpsaischophpEditFieldLabel = uilabel(app.GridLayout);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.SlnlpEditFieldLabel = uilabel(app.GridLayout);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SoLanLap = uieditfield(app.GridLayout, 'numeric');
            app.ChnphngphptmnghimLabel = uilabel(app.GridLayout);
            app.ChnphngphptmnghimLabel.Text = 'Chọn phương pháp tìm nghiệm';
            app.ChonPPNghiem = uidropdown(app.GridLayout);
            app.ChonPPNghiem.Items = {'Chia đôi', 'Lặp', 'Newton (Tiếp tuyến)'};
            app.ChonPPNghiem.Value = 'Chia đôi';
            app.PhuongTrinh_1 = uieditfield(app.GridLayout, 'text');
            app.PhuongTrinh_1.Value = 'x - sin(x) - 0.25';
            app.PhanLyB = uieditfield(app.GridLayout, 'numeric');
            app.PhanLyB.Value = 2;
            app.PhanLyA = uieditfield(app.GridLayout, 'numeric');
            app.PhanLyA.Value = -1;
            app.Nhpphngtrnh2Label = uilabel(app.GridLayout);
            app.Nhpphngtrnh2Label.Text = 'Nhập phương trình 2';
            app.PhuongTrinh_2 = uieditfield(app.GridLayout, 'text');
            app.PhuongTrinh_2.Value = 'sin(x) + 0.25';
            app.SaiSo_1 = uieditfield(app.GridLayout, 'numeric');
            app.SaiSo_1.Value = 0.005;
            app.TimNghiem = uibutton(app.GridLayout, 'push');
            app.TimNghiem.ButtonPushedFcn = createCallbackFcn(app, @TimNghiemPushed, true);
            app.TimNghiem.Text = 'Tìm Nghiệm';
            app.NhpkhongphnlynghimLabel_3 = uilabel(app.GridLayout);
            app.NhpkhongphnlynghimLabel_3.Text = 'Nhập khoảng phân ly';
            app.KtqunghimEditFieldLabel_2 = uilabel(app.GridLayout);
            app.KtqunghimEditFieldLabel_2.Text = 'Kết quả nghiệm';
            app.KetQuaNghiem = uieditfield(app.GridLayout, 'text');
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.GridLayout_2 = uigridlayout(app.NisuyTab);
            app.DoThiNoiSuy = uiaxes(app.GridLayout_2);
            title(app.DoThiNoiSuy, 'Vẽ đồ thị hàm số nội suy và dữ liệu thực')
            xlabel(app.DoThiNoiSuy, 'X')
            ylabel(app.DoThiNoiSuy, 'Y')
            zlabel(app.DoThiNoiSuy, 'Z')
            app.NhpdliuxEditFieldLabel = uilabel(app.GridLayout_2);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.GridLayout_2);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.CanNoiSuy = uieditfield(app.GridLayout_2, 'numeric');
            app.CanNoiSuy.Value = 5;
            app.ChnphngphpnisuyDropDownLabel = uilabel(app.GridLayout_2);
            app.ChnphngphpnisuyDropDownLabel.Text = 'Chọn phương pháp nội suy';
            app.ChonPPNoiSuy = uidropdown(app.GridLayout_2);
            app.ChonPPNoiSuy.Items = {'Newton', 'Lagrange'};
            app.ChonPPNoiSuy.Value = 'Newton';
            app.NhpdliuyEditFieldLabel = uilabel(app.GridLayout_2);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.DuLieuY_1 = uieditfield(app.GridLayout_2, 'text');
            app.DuLieuY_1.Value = '17 17.5 76 210.5 1970';
            app.DuLieuX_1 = uieditfield(app.GridLayout_2, 'text');
            app.DuLieuX_1.Value = '1 2 3 4 7';
            app.Noisuy = uibutton(app.GridLayout_2, 'push');
            app.Noisuy.ButtonPushedFcn = createCallbackFcn(app, @NoisuyPushed, true);
            app.Noisuy.Text = 'Nội suy';
            app.KtqudonLabel_2 = uilabel(app.GridLayout_2);
            app.KtqudonLabel_2.Text = 'Kết quả nội suy';
            app.KetQuaNoiSuy = uieditfield(app.GridLayout_2, 'text');
            app.KtqudonLabel_3 = uilabel(app.GridLayout_2);
            app.KtqudonLabel_3.Text = 'Kết quả đa thức nội suy';
            app.KetQuaDaThuc = uieditfield(app.GridLayout_2, 'text');
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.GridLayout_3 = uigridlayout(app.HiquyTab);
            app.DoThiHoiQuy = uiaxes(app.GridLayout_3);
            title(app.DoThiHoiQuy, 'Vẽ đồ thị hàm số hồi quy và dữ liệu thực')
            xlabel(app.DoThiHoiQuy, 'X')
            ylabel(app.DoThiHoiQuy, 'Y')
            zlabel(app.DoThiHoiQuy, 'Z')
            app.DuDoanHoiQuy = uieditfield(app.GridLayout_3, 'numeric');
            app.DuDoanHoiQuy.Value = 2.5;
            app.NhpdliuxEditField_2Label = uilabel(app.GridLayout_3);
            app.NhpdliuxEditField_2Label.Text = 'Nhập dữ liệu x';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.GridLayout_3);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.ChnphngphphiquyDropDownLabel = uilabel(app.GridLayout_3);
            app.ChnphngphphiquyDropDownLabel.Text = 'Chọn phương pháp hồi quy';
            app.ChonPPHoiQuy = uidropdown(app.GridLayout_3);
            app.ChonPPHoiQuy.Items = {'Tuyến tính(y=ax+b)', 'Lũy thừa(y=ae^bx)', 'Mũ(y=ax^b)', 'Logarit(y=ax/b+x)'};
            app.ChonPPHoiQuy.Value = 'Tuyến tính(y=ax+b)';
            app.NhpdliuyEditField_2Label = uilabel(app.GridLayout_3);
            app.NhpdliuyEditField_2Label.Text = 'Nhập dữ liệu y';
            app.DuLieuY_2 = uieditfield(app.GridLayout_3, 'text');
            app.DuLieuY_2.Value = '0.2 0.7 3.4 4.8 5.6';
            app.DuLieuX_2 = uieditfield(app.GridLayout_3, 'text');
            app.DuLieuX_2.Value = '1 2 3 4 5';
            app.HoiQuy = uibutton(app.GridLayout_3, 'push');
            app.HoiQuy.ButtonPushedFcn = createCallbackFcn(app, @HoiQuyPushed, true);
            app.HoiQuy.Text = 'Hồi quy';
            app.KetQuaHoiQuy = uieditfield(app.GridLayout_3, 'text');
            app.KtquphngtrnhhiquyEditFieldLabel_2 = uilabel(app.GridLayout_3);
            app.KtquphngtrnhhiquyEditFieldLabel_2.Text = 'Kết quả phương trình hồi quy';
            app.KtqudonLabel = uilabel(app.GridLayout_3);
            app.KtqudonLabel.Text = 'Kết quả hồi quy';
            app.KetQuaDuDoan = uieditfield(app.GridLayout_3, 'text');
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.GridLayout_4 = uigridlayout(app.ohmTab);
            app.NhpdliuxEditField_3Label = uilabel(app.GridLayout_4);
            app.NhpdliuxEditField_3Label.Text = 'Nhập dữ liệu x';
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.GridLayout_4);
            app.NhpgitrcntnhohmEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.CanDaoHam = uieditfield(app.GridLayout_4, 'numeric');
            app.CanDaoHam.Value = 0.5;
            app.NhpdliuyEditField_3Label = uilabel(app.GridLayout_4);
            app.NhpdliuyEditField_3Label.Text = 'Nhập dữ liệu y';
            app.NhphmsEditFieldLabel = uilabel(app.GridLayout_4);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.ChnphngphpohmDropDownLabel = uilabel(app.GridLayout_4);
            app.ChnphngphpohmDropDownLabel.Text = 'Chọn phương pháp đạo hàm';
            app.ChngitrsaisDropDownLabel = uilabel(app.GridLayout_4);
            app.ChngitrsaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.SaiSo_2 = uidropdown(app.GridLayout_4);
            app.SaiSo_2.Items = {'O(h)', 'O(h^2)'};
            app.SaiSo_2.Value = 'O(h)';
            app.DuLieuX_3 = uieditfield(app.GridLayout_4, 'text');
            app.DuLieuX_3.Value = '0.1 0.3 0.5 0.7 0.9';
            app.DuLieuY_3 = uieditfield(app.GridLayout_4, 'text');
            app.DuLieuY_3.Value = '0.1002 0.3047 0.5236 0.7754 1.1198';
            app.HamSo_1 = uieditfield(app.GridLayout_4, 'text');
            app.HamSo_1.Value = 'asin(x)';
            app.ChonPPDaoHam = uidropdown(app.GridLayout_4);
            app.ChonPPDaoHam.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.ChonPPDaoHam.Value = 'Xấp xỉ tiến';
            app.DaoHam = uibutton(app.GridLayout_4, 'push');
            app.DaoHam.ButtonPushedFcn = createCallbackFcn(app, @DaoHamPushed, true);
            app.DaoHam.Text = 'Đạo hàm';
            app.KtquEditFieldLabel_2 = uilabel(app.GridLayout_4);
            app.KtquEditFieldLabel_2.Text = 'Kết quả';
            app.KetQuaDaoHam = uieditfield(app.GridLayout_4, 'text');
            app.Hoac_1 = uiswitch(app.GridLayout_4, 'toggle');
            app.Hoac_1.Items = {'Dữ liệu', 'Hàm số'};
            app.Hoac_1.Value = 'Dữ liệu';
            app.NhpbchLabel = uilabel(app.GridLayout_4);
            app.NhpbchLabel.Text = 'Nhập bước h';
            app.BuocH = uieditfield(app.GridLayout_4, 'numeric');
            app.BuocH.Value = 0.25;
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.GridLayout_5 = uigridlayout(app.TchphnTab);
            app.NhpdliuxEditField_4Label = uilabel(app.GridLayout_5);
            app.NhpdliuxEditField_4Label.Text = 'Nhập dữ liệu x';
            app.CanB = uieditfield(app.GridLayout_5, 'numeric');
            app.CanB.Value = 1;
            app.CanA = uieditfield(app.GridLayout_5, 'numeric');
            app.NDoan = uieditfield(app.GridLayout_5, 'numeric');
            app.NDoan.Value = 10;
            app.NhpdliuyEditField_4Label = uilabel(app.GridLayout_5);
            app.NhpdliuyEditField_4Label.Text = 'Nhập dữ liệu y';
            app.NhphmsEditField_2Label = uilabel(app.GridLayout_5);
            app.NhphmsEditField_2Label.Text = 'Nhập hàm số';
            app.ChnphngphptchphnDropDownLabel = uilabel(app.GridLayout_5);
            app.ChnphngphptchphnDropDownLabel.Text = 'Chọn phương pháp tích phân';
            app.HamSo_2 = uieditfield(app.GridLayout_5, 'text');
            app.HamSo_2.Value = 'x^3*sin(x)';
            app.HocLabel_2 = uilabel(app.GridLayout_5);
            app.HocLabel_2.Text = 'Hoặc';
            app.ChonPPTichPhan = uidropdown(app.GridLayout_5);
            app.ChonPPTichPhan.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.ChonPPTichPhan.Value = 'Hình thang';
            app.DuLieuY_4 = uieditfield(app.GridLayout_5, 'text');
            app.DuLieuX_4 = uieditfield(app.GridLayout_5, 'text');
            app.TichPhan = uibutton(app.GridLayout_5, 'push');
            app.TichPhan.ButtonPushedFcn = createCallbackFcn(app, @TichPhanButtonPushed, true);
            app.TichPhan.Text = 'Tích phân';
            app.NhpkhongphnlynghimLabel_2 = uilabel(app.GridLayout_5);
            app.NhpkhongphnlynghimLabel_2.Text = 'Nhập Cận';
            app.NhpNEditFieldLabel_2 = uilabel(app.GridLayout_5);
            app.NhpNEditFieldLabel_2.Text = 'Nhập N';
            app.KtquEditFieldLabel_3 = uilabel(app.GridLayout_5);
            app.KtquEditFieldLabel_3.Text = 'Kết quả';
            app.KetQuaTichPhan = uieditfield(app.GridLayout_5, 'text');
            app.GiithiuNhmTab = uitab(app.TabGroup);
            app.GiithiuNhmTab.Title = 'Giới thiệu Nhóm';
            app.hoac = uilabel(app.GiithiuNhmTab);
            app.hoac.Text = 'Bùi Tấn Đạt - 21207133';
            app.hoac_2 = uilabel(app.GiithiuNhmTab);
            app.hoac_2.Text = 'Vũ Xuân Đức - 21207140';
            app.hoac_3 = uilabel(app.GiithiuNhmTab);
            app.hoac_3.Text = 'Nguyễn Thanh Tâm - 21207215';
            app.hoac_4 = uilabel(app.GiithiuNhmTab);
            app.hoac_4.Text = 'Nguyễn Quang Thành Đạt - 21207134';
            app.hoac_5 = uilabel(app.GiithiuNhmTab);
            app.hoac_5.Text = 'Liên Hiệp Quốc - 21207250';
            app.Image = uiimage(app.GiithiuNhmTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ANHNHOM', 'TanDatDat.jpg');
            app.Image_3 = uiimage(app.GiithiuNhmTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'ANHNHOM', 'ThanhTam.jpg');
            app.Image_4 = uiimage(app.GiithiuNhmTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'ANHNHOM', 'XuanDuc.jpg');
            app.Image_5 = uiimage(app.GiithiuNhmTab);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'ANHNHOM', 'HoangHai.jpg');
            app.hoac_6 = uilabel(app.GiithiuNhmTab);
            app.hoac_6.Text = 'Trần Hoàng Hải - 21207150';
            app.Image_6 = uiimage(app.GiithiuNhmTab);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'ANHNHOM', 'ThanhDat.jpg');
            app.Image_7 = uiimage(app.GiithiuNhmTab);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'ANHNHOM', 'HiepQuoc.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PJR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end